classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainMenuPanel                   matlab.ui.container.Panel
        WelcometoLeDucPokerPlayerLabel  matlab.ui.control.Label
        ExitButton                      matlab.ui.control.Button
        TrainAIButton                   matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        TrainAIPanel                    matlab.ui.container.Panel
        ExplorationRateField            matlab.ui.control.EditField
        ExplorationRateLabel            matlab.ui.control.Label
        LearningRateField               matlab.ui.control.EditField
        LearningRateLabel               matlab.ui.control.Label
        NumGamesField                   matlab.ui.control.EditField
        NumberofGamesLabel              matlab.ui.control.Label
        TrainingLogTextArea             matlab.ui.control.TextArea
        TextAreaLabel                   matlab.ui.control.Label
        TrainingProgressBar             matlab.ui.control.Gauge
        ProgressLabel                   matlab.ui.control.Label
        TrainingTimeLabel               matlab.ui.control.Label
        WinsStatsLabel                  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TrainStatusLabel                matlab.ui.control.Label
        TrainTitleLabel                 matlab.ui.control.Label
        BackToMenuButton                matlab.ui.control.Button
        StopTrainingButton              matlab.ui.control.Button
        StartTrainingButton             matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        PlayGamePanel                   matlab.ui.container.Panel
        BacktoMainMenuButton            matlab.ui.control.Button
        PlaytheGameLabel                matlab.ui.control.Label
        PotLabel                        matlab.ui.control.Label
        AIBetLabel                      matlab.ui.control.Label
        PlayerBetLabel                  matlab.ui.control.Label
        AICardLabel                     matlab.ui.control.Label
        CommunityCardLabel              matlab.ui.control.Label
        PlayerCardLabel                 matlab.ui.control.Label
        AICardImage                     matlab.ui.control.Image
        CommunityCardImage              matlab.ui.control.Image
        PlayerCardImage                 matlab.ui.control.Image
        NewGameButton                   matlab.ui.control.Button
        FoldButton                      matlab.ui.control.Button
        BetRaiseButton                  matlab.ui.control.Button
        CheckCallButton                 matlab.ui.control.Button
        WinnerLabel                     matlab.ui.control.Label
        TurnIndicatorLabel              matlab.ui.control.Label
        GameStageLabel                  matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        AIChipsLabel                    matlab.ui.control.Label
        PlayerChipsLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        GameManager              % RLLeducPokerManager instance
        GameInProgress = false   % Boolean flag
        WaitingForPlayerAction = false  % Boolean flag
        CurrentGameStage = ""    % "PreFlop" or "Flop"
        PlayerAction = ""           % Store player's chosen action
        TrainingInProgress = false
        StopTrainingFlag = false
        TrainingStartTime
        TrainedGameManager 
        HasTrainedAI = false
    end
    
    methods (Access = private)

    function showMainMenu(app)
            app.MainMenuPanel.Visible = 'on';
            app.PlayGamePanel.Visible = 'off';
            app.TrainAIPanel.Visible = 'off';
            app.SettingsPanel.Visible = 'off';
        end

    function showPlayGame(app)
            app.MainMenuPanel.Visible = 'off';
            app.PlayGamePanel.Visible = 'on';
            app.TrainAIPanel.Visible = 'off';
            app.SettingsPanel.Visible = 'off';
        end

    function showTrainAI(app)
            app.MainMenuPanel.Visible = 'off';
            app.PlayGamePanel.Visible = 'off';
            app.TrainAIPanel.Visible = 'on';
            app.SettingsPanel.Visible = 'off';
        end

    function showSettings(app)
            app.MainMenuPanel.Visible = 'off';
            app.PlayGamePanel.Visible = 'off';
            app.TrainAIPanel.Visible = 'off';
            app.SettingsPanel.Visible = 'on';
        end
        
    function startupFcn(app)
            % Initialize app state
            app.GameManager = [];
            app.CurrentPanel = 'MainMenu';
            app.GameInProgress = false;

            % Show main menu initially
            app.showMainMenu();

            % Set window title
            app.UIFigure.Name = 'Leduc Poker with AI';
        end
    
    function initializeGameInterface(app)
        % Reset all game UI elements
        app.resetGameUI();
        
        % Initialize chip displays
        app.updateChipDisplays();
        
        % Initialize status
        app.updateGameStatus('Welcome to Leduc Poker!');
        
        % Enable new game button, disable action buttons
        app.NewGameButton.Enable = 'on';
        app.disableActionButtons();
        
        % Clear card displays
        app.clearCardDisplays();
    end
    
    function resetGameUI(app)
        % Reset pot display
        app.PotLabel.Text = 'Pot: 0';
        
        % Reset game stage indicator
        app.GameStageLabel.Text = 'Ready to play';
        
        % Reset turn indicator
        app.TurnIndicatorLabel.Text = '';
        
        % Hide winner announcement
        app.WinnerLabel.Visible = 'off';
        
        % Reset any previous game state flags
        app.GameInProgress = false;
        app.WaitingForPlayerAction = false;
    end
    
    function updateChipDisplays(app)
        if ~isempty(app.GameManager)
            app.PlayerChipsLabel.Text = sprintf('Your Chips: %d', app.GameManager.Player1.NumChips);
            app.AIChipsLabel.Text = sprintf('AI Chips: %d', app.GameManager.Player2.NumChips);
        else
            app.PlayerChipsLabel.Text = 'Your Chips: 20';
            app.AIChipsLabel.Text = 'AI Chips: 20';
        end
    end
    
    function clearCardDisplays(app)
        % Hide all card images initially
        imagePath = app.getCardBackImagePath();
        app.AICardImage.ImageSource = imagePath;
        app.PlayerCardImage.ImageSource = imagePath;
        app.CommunityCardImage.ImageSource = imagePath;

        % Set placeholder text or images
        app.PlayerCardLabel.Text = 'Your Card';
        app.CommunityCardLabel.Text = 'Community Card';
        app.AICardLabel.Text = 'AI Card';
    end
        
    function startNewGame(app)
        try
            % Reset for new game
            app.GameManager.resetForNewGame();
            
            % Update displays
            app.updateChipDisplays();
            app.updatePotDisplay();
            app.clearCardDisplays();
            
            % Deal starting cards
            app.GameManager.dealStartingCards();
            
            % Show player's card immediately
            app.displayPlayerCard();
            
            % Add blind bets
            app.GameManager.addBlindBetsToPot();
            app.updatePotDisplay();
            
            % Initialize game state
            app.GameInProgress = true;
            app.WaitingForPlayerAction = true;
            app.CurrentGameStage = "PreFlop";
            
            % Update status
            app.updateGameStatus('Pre-Flop: Your turn to act');
            app.GameStageLabel.Text = 'Pre-Flop Round';
            
            % Enable action buttons for player
            app.enableActionButtons();
            
            % Disable new game button during play
            app.NewGameButton.Enable = 'off';
            
            % Set up for pre-flop betting
            app.setupPreFlopBetting();
            
        catch ME
            uialert(app.UIFigure, ['Error starting new game: ' ME.message], 'Game Error');
            app.enableNewGameButton();
        end
    end
    
    function setupPreFlopBetting(app)
        % Initialize game state for betting
        app.GameManager.GameState.IsPreFlop = true;
        app.GameManager.GameState.NumRaisesThisStage = 0;
        app.GameManager.GameState.SelfBetAmount = 1; % Blind bet
        app.GameManager.GameState.OppBetAmount = 1;  % Blind bet
        app.GameManager.GameState.IsStartingPlayer = app.GameManager.StartWithPlayer1;
        
        % Update UI to reflect current betting state
        app.updateBettingDisplay();
    end

    function displayPlayerCard(app)
        if ~isempty(app.GameManager.Player1.Card) && app.GameManager.Player1.Card.Value > 0
            card = app.GameManager.Player1.Card;
            
            % Update label text
            cardText = sprintf('%s of %s', card.Rank, card.Suit);
            app.PlayerCardLabel.Text = cardText;
            
            % Set card image
            imagePath = app.getCardImagePath(card);
            app.PlayerCardImage.ImageSource = imagePath;
            app.PlayerCardImage.Visible = 'on';

        else
            % Hide or show card back
            app.PlayerCardLabel.Text = 'No Card';
            imagePath = app.getCardBackImagePath();
            app.PlayerCardImage.ImageSource = imagePath;
            app.PlayerCardImage.Visible = 'on';
        end

    end
    
    function displayCommunityCard(app)
        if ~isempty(app.GameManager.Table.CommunityCard) && app.GameManager.Table.CommunityCard.Value > 0
            card = app.GameManager.Table.CommunityCard;

            % Update label text
            cardText = sprintf('%s of %s', card.Rank, card.Suit);
            app.CommunityCardLabel.Text = cardText;

            % Set card image
            imagePath = app.getCardImagePath(card);
            app.CommunityCardImage.ImageSource = imagePath;
            app.CommunityCardImage.Visible = 'on';
        else
            app.CommunityCardLabel.Text = 'Community Card';
            imagePath = app.getCardBackImagePath();
            app.CommunityCardImage.ImageSource = imagePath;
            app.CommunityCardImage.Visible = 'on';
        end
    end
    
    function displayAICard(app, faceUp)
        if faceUp && ~isempty(app.GameManager.Player2.Card) && app.GameManager.Player2.Card.Value > 0
            % Show AI's actual card (during showdown)
            card = app.GameManager.Player2.Card;
            cardText = sprintf('%s of %s', card.Rank, card.Suit);
            app.AICardLabel.Text = cardText;

            % Show actual card image
            imagePath = app.getCardImagePath(card);
            app.AICardImage.ImageSource = imagePath;
        else
            % Show card back during play
            app.AICardLabel.Text = 'Hidden';

            % Show card back image
            imagePath = app.getCardBackImagePath();
            app.AICardImage.ImageSource = imagePath;
        end
        app.AICardImage.Visible = 'on';

    end
    
    function updateGameStatus(app, statusText)
        app.StatusLabel.Text = statusText;
    end
    
    function updatePotDisplay(app)
        if ~isempty(app.GameManager)
            app.PotLabel.Text = sprintf('Pot: %d', app.GameManager.Table.NumPotChips);
        end
    end
    
    function updateBettingDisplay(app)
        if ~isempty(app.GameManager) && ~isempty(app.GameManager.GameState)
            playerBet = app.GameManager.Player1.BetAmount;
            aiBet = app.GameManager.Player2.BetAmount;
            
            app.PlayerBetLabel.Text = sprintf('Your Bet: %d', playerBet);
            app.AIBetLabel.Text = sprintf('AI Bet: %d', aiBet);
        end
    end
    
    function enableActionButtons(app)
        app.CheckCallButton.Enable = 'on';
        app.BetRaiseButton.Enable = 'on';
        app.FoldButton.Enable = 'on';
        
        % Update button text based on current situation
        app.updateActionButtonText();
    end
    
    function disableActionButtons(app)
        app.CheckCallButton.Enable = 'off';
        app.BetRaiseButton.Enable = 'off';
        app.FoldButton.Enable = 'off';
    end
    
    function updateActionButtonText(app)
        % Determine if it's a check or call situation
        if ~isempty(app.GameManager) && ~isempty(app.GameManager.GameState)
            if app.GameManager.GameState.SelfBetAmount < app.GameManager.GameState.OppBetAmount
                app.CheckCallButton.Text = 'Call';
            else
                app.CheckCallButton.Text = 'Check';
            end
        else
            app.CheckCallButton.Text = 'Check/Call';
        end
    end
    
    function enableNewGameButton(app)
        app.NewGameButton.Enable = 'on';
        app.GameInProgress = false;
    end
    
    function updateAllDisplaysAfterAction(app)
        % Update pot
        app.updatePotDisplay();
        
        % Update chip counts
        app.updateChipDisplays();
        
        % Update betting amounts if you have those labels
        app.updateBettingDisplay();

        % Check for game over condition after every action
        if app.checkForGameOver()
            return; % Game over, stop processing
        end
    end

    function initializeTrainingDisplay(app)
        % Set default values
        app.NumGamesField.Value = '1000';
        app.LearningRateField.Value = '0.1';
        app.ExplorationRateField.Value = '0.3';

        % Reset UI state
        app.resetTrainingUI();

        % Set initial messages
        app.TrainStatusLabel.Text = 'Ready to train AI players';
        app.WinsStatsLabel.Text = 'No training data yet';
        app.TrainingTimeLabel.Text = '';

        % Clear progress
        app.TrainingProgressBar.Value = 0;
    end
    
    function setupPlayersForGame(app)
        % If we already have trained players, just reconfigure them for gameplay
        if app.HasTrainedAI && ~isempty(app.TrainedGameManager)
            startingChips = app.GameManager.GameSettings.NumStartingChips;
            % Use the existing trained players
            app.GameManager.Player1 = Player('Player', ...
                app.GameManager.GameSettings.NumStartingChips, ...
                app.GameManager.GameSettings.getEmptyCard(), ...
                false);

            % Keep the trained AI player but set it for gameplay
            app.GameManager.Player2.setExplorationRate(0.05);
            app.GameManager.Player2.Name = 'Trained AI';
            app.GameManager.Player2.NumChips = startingChips;

            app.StatusLabel.Text = sprintf('Playing against trained AI (Q-table size: %d)', ...
                app.GameManager.Player2.QTable.Count);
        else
            % Create new untrained players
            app.GameManager.Player1 = Player('Player', ...
                app.GameManager.GameSettings.NumStartingChips, ...
                app.GameManager.GameSettings.getEmptyCard(), ...
                false);

            app.GameManager.Player2 = RLPlayer('Random AI', ...
                app.GameManager.GameSettings.NumStartingChips, ...
                app.GameManager.GameSettings.getEmptyCard());

            app.GameManager.Player2.setExplorationRate(0.8); % High exploration = random
            app.StatusLabel.Text = 'Playing against untrained AI (random play)';
        end
    end

    function trained = hasTrainedAI(app)
        trained = app.HasTrainedAI && ~isempty(app.TrainedGameManager);

        if trained
            fprintf('AI is trained - Q-table count: %d\n', app.TrainedGameManager.Player2.QTable.Count);
        else
            fprintf('AI is not trained\n');
        end

    end
    
    function executeAIAction(app)
        player = app.GameManager.Player2;  % AI player
        opponent = app.GameManager.Player1; % Human player

        % Update game state for AI
        utils.updateGameState(app.GameManager, player, opponent);

        % Get AI action
        gameStateEnc = utils.encodeGameStateAsVec(app.GameManager.GameState);
        aiAction = player.takeAction(gameStateEnc);

        % Show AI's action
        if app.hasTrainedAI()
            actionText = sprintf('Trained AI chose: %s', app.getActionText(aiAction));
        else
            actionText = sprintf('Random AI chose: %s', app.getActionText(aiAction));
        end
        app.updateGameStatus(actionText);
        pause(1);

        % Execute AI action
        switch aiAction
            case 'C' % check/call
                discrepancy = app.GameManager.GameState.OppBetAmount - app.GameManager.GameState.SelfBetAmount;
                if discrepancy > 0
                    player.placeBet(discrepancy);
                    app.GameManager.Table.receiveBet(discrepancy);
                end

            case 'B' % bet/raise
                discrepancy = app.GameManager.GameState.OppBetAmount - app.GameManager.GameState.SelfBetAmount;
                if discrepancy > 0
                    player.placeBet(discrepancy);
                    app.GameManager.Table.receiveBet(discrepancy);
                end

                if app.GameManager.GameState.NumRaisesThisStage < app.GameManager.GameSettings.MaxNumRaisesPerStage
                    if strcmp(app.CurrentGameStage, "PreFlop")
                        betAmount = app.GameManager.GameSettings.PreFlopRaiseSize;
                    else
                        betAmount = app.GameManager.GameSettings.FlopRaiseSize;
                    end
                    player.placeBet(betAmount);
                    app.GameManager.Table.receiveBet(betAmount);
                    app.GameManager.GameState.NumRaisesThisStage = app.GameManager.GameState.NumRaisesThisStage + 1;
                end

            case 'F' % fold
                % AI folded - END GAME IMMEDIATELY
                app.endGameWithFold(opponent); % opponent (Human) wins
                return; % CRITICAL: Don't continue processing
        end

        app.updateAllDisplaysAfterAction();
    end
    
    function updateAIStatusDisplay(app)
        if app.hasTrainedAI()
            % You could add a label showing AI status
            if isprop(app, 'AIStatusLabel')
                app.AIStatusLabel.Text = 'AI Status: Trained ✓';
                app.AIStatusLabel.FontColor = [0, 0.6, 0]; % Green
            end
        else
            if isprop(app, 'AIStatusLabel')
                app.AIStatusLabel.Text = 'AI Status: Untrained (Random)';
                app.AIStatusLabel.FontColor = [0.8, 0.4, 0]; % Orange
            end
        end
end
    
    function checkGameStateAndContinue(app)
    % Check if we need another betting round or move to next stage
    playerBet = app.GameManager.Player1.BetAmount;
    aiBet = app.GameManager.Player2.BetAmount;

    fprintf('Checking game state - Player bet: %d, AI bet: %d\n', playerBet, aiBet);

    % Check if betting is balanced
    if playerBet == aiBet && app.GameManager.GameState.SelfBetAmount == app.GameManager.GameState.OppBetAmount
        % Betting is even - move to next stage
        fprintf('Betting is even, moving to next stage\n');

        if strcmp(app.CurrentGameStage, "PreFlop")
            app.moveToFlopStage();
        else
            app.handleShowdown();
        end
    else
        % Need another betting round
        fprintf('Betting not even, continuing betting\n');
        app.updateGameStatus('Betting continues - your turn...');
        pause(1);
        app.enableActionButtons();
        app.WaitingForPlayerAction = true;
        app.updateActionButtonText();
    end
end
    
    function imagePath = getCardImagePath(app, card)
    % Convert card struct to image file path
    appDir = fileparts(which('app1.mlapp'));
    if isempty(card) || card.Value == 0
        % Return card back for empty/hidden cards
        imagePath = fullfile(appDir,'assets', 'cards', 'backs', 'card_back.png');
        return;
    end

    % Convert rank and suit to lowercase for filename
    rank = lower(card.Rank);
    suit = lower(card.Suit);

    % Create filename
    filename = sprintf('%s_%s.png', rank, suit);

    % Full path to image
    imagePath = fullfile(appDir,'assets', 'cards', filename);

    % Check if file exists
    if ~isfile(imagePath)
        fprintf('Warning: Card image not found: %s\n', imagePath);
        % Fallback to card back
        imagePath = fullfile(appDir,'assets', 'cards', 'backs', 'card_back.png');
    end
end

    function imagePath = getCardBackImagePath(app)
    % Return path to card back image
    appDir = fileparts(which('app1.mlapp'));
    imagePath = fullfile(appDir,'assets', 'cards', 'backs', 'card_back.png');
end

    end

    methods (Access = private)
     
    function processPlayerAction(app, action)
            % Disable action buttons immediately to prevent double-clicks
            app.disableActionButtons();
            app.WaitingForPlayerAction = false;

            % Store the player's action
            app.PlayerAction = action;

            % Update status to show player's action
            actionText = app.getActionText(action);
            app.updateGameStatus(sprintf('You chose: %s', actionText));

            % Process the action with a small delay for better UX
            pause(0.5);

            % Now process the game logic - SIMPLIFIED VERSION
            app.processGameLogicWithPlayerAction(action);
        end
    
    function processGameLogicWithPlayerAction(app, playerAction)
            try
                % Manually process the player's action first
                app.executePlayerAction(playerAction);
                
                if ~app.GameInProgress
                    return; % Game ended, don't continue
                end
                % Then let AI take its action
                app.executeAIAction();
                
                if ~app.GameInProgress
                    return; % Game ended, don't continue
                end
                % Check game state and continue
                app.checkGameStateAndContinue();

            catch ME
                app.handleGameError(ME);
            end
     end
    
    function executePlayerAction(app, action)
         player = app.GameManager.Player1;
         opponent = app.GameManager.Player2;

         % Update game state before action
         utils.updateGameState(app.GameManager, player, opponent);

         switch action
             case 'C' % check/call
                 discrepancy = app.GameManager.GameState.OppBetAmount - app.GameManager.GameState.SelfBetAmount;
                 if discrepancy > 0
                     % Use the player's placeBet method and table's receiveBet method
                     player.placeBet(discrepancy);
                     app.GameManager.Table.receiveBet(discrepancy);
                 end

             case 'B' % bet/raise
                 discrepancy = app.GameManager.GameState.OppBetAmount - app.GameManager.GameState.SelfBetAmount;
                 if discrepancy > 0
                     player.placeBet(discrepancy);
                     app.GameManager.Table.receiveBet(discrepancy);
                 end

                 % Add the raise amount
                 if app.GameManager.GameState.NumRaisesThisStage < app.GameManager.GameSettings.MaxNumRaisesPerStage
                     if strcmp(app.CurrentGameStage, "PreFlop")
                         betAmount = app.GameManager.GameSettings.PreFlopRaiseSize;
                     else
                         betAmount = app.GameManager.GameSettings.FlopRaiseSize;
                     end
                     player.placeBet(betAmount);
                     app.GameManager.Table.receiveBet(betAmount);
                     app.GameManager.GameState.NumRaisesThisStage = app.GameManager.GameState.NumRaisesThisStage + 1;
                 end

             case 'F' % fold
                 % Player folded - END GAME IMMEDIATELY
                 app.endGameWithFold(opponent); % opponent (AI) wins
                 return; % CRITICAL: Don't continue processing
                 
         end

         % Update displays after player action
         app.updateAllDisplaysAfterAction();
     end

    function actionText = getActionText(app, action)
        switch action
            case 'C'
                % Determine if it's check or call
                if app.GameManager.GameState.SelfBetAmount < app.GameManager.GameState.OppBetAmount
                    actionText = 'Call';
                else
                    actionText = 'Check';
                end
            case 'B'
                actionText = 'Bet/Raise';
            case 'F'
                actionText = 'Fold';
            otherwise
                actionText = 'Unknown';
        end
    end
    
    end

    methods (Access = private)
    
    function continueGameAfterPlayerAction(app)
        try
            % Process one complete betting round
            app.processBettingRound();
            
        catch ME
            app.handleGameError(ME);
        end
    end
    
    function processBettingRound(app)
        % This method handles the interaction with your existing game logic
        
        % Setup current and opponent players based on turn order
        if app.GameManager.StartWithPlayer1
            curPlayer = app.GameManager.Player1;
            oppPlayer = app.GameManager.Player2;
        else
            curPlayer = app.GameManager.Player2;
            oppPlayer = app.GameManager.Player1;
        end
        
        % Override the human player's takeAction method temporarily
        originalTakeAction = curPlayer.takeAction;
        curPlayer.takeAction = @(gameStateEnc) app.PlayerAction;
        
        % Process the current stage (Pre-Flop or Flop)
        isPreFlop = strcmp(app.CurrentGameStage, "PreFlop");
        winner = app.GameManager.playOneStage(isPreFlop);
        
        % Restore original takeAction method
        curPlayer.takeAction = originalTakeAction;
        
        % Update displays after the betting round
        app.updateAllDisplaysAfterAction();
        
        % Check if someone won by folding
        if ~isempty(winner)
            app.handleGameEnd(winner, 'fold');
            return;
        end
        
        % Move to next stage or end game
        app.advanceGameStage();
    end
    
    function advanceGameStage(app)
        if strcmp(app.CurrentGameStage, "PreFlop")
            % Move to Flop stage
            app.moveToFlopStage();
        else
            % Game over - determine winner by cards
            app.handleShowdown();
        end
    end
    
    function moveToFlopStage(app)
        fprintf('Moving to Flop stage\n');

        app.CurrentGameStage = "Flop";

        % Reveal community card
        communityCard = app.GameManager.revealCommunityCard();
        app.displayCommunityCard();

        % Reset betting amounts for flop stage
        app.GameManager.Player1.BetAmount = 0;
        app.GameManager.Player2.BetAmount = 0;
        app.GameManager.GameState.SelfBetAmount = 0;
        app.GameManager.GameState.OppBetAmount = 0;
        app.GameManager.GameState.NumRaisesThisStage = 0;

        % Update displays
        app.updateAllDisplaysAfterAction();

        % Show both cards to player
        playerCard = app.GameManager.Player1.Card;
        statusText = sprintf('Flop Round!\nYour card: %s of %s\nCommunity: %s of %s\nYour turn!', ...
            playerCard.Rank, playerCard.Suit, communityCard.Rank, communityCard.Suit);
        app.StatusLabel.Text = statusText;

        % Enable action buttons for flop betting
        pause(2); % Let player see the community card
        app.enableActionButtons();
        app.WaitingForPlayerAction = true;
        app.updateActionButtonText();
    end
    
    function setupFlopBetting(app)
        % Enable action buttons for flop betting
        app.enableActionButtons();
        app.WaitingForPlayerAction = true;
        
        % Update button text for flop (different betting amounts)
        app.updateActionButtonText();
    end
    
    function handleShowdown(app)
        fprintf('Handling showdown\n');

        % Update status
        app.updateGameStatus('Showdown: Revealing all cards...');

        % Show AI card
        app.displayAICard(true);

        % Show all cards to player
        playerCard = app.GameManager.Player1.Card;
        aiCard = app.GameManager.Player2.Card;
        communityCard = app.GameManager.Table.CommunityCard;

        cardsText = sprintf('Final Cards:\nYour card: %s of %s\nAI card: %s of %s\nCommunity: %s of %s', ...
            playerCard.Rank, playerCard.Suit, ...
            aiCard.Rank, aiCard.Suit, ...
            communityCard.Rank, communityCard.Suit);

        app.StatusLabel.Text = cardsText;

        pause(2); % Dramatic pause

        % Determine winner using your existing logic
        winner = utils.determineWinner(app.GameManager);

        % Handle the pot and announce winner
        if ~isempty(winner)
            fprintf('Winner: %s\n', winner.Name);
            potAmount = app.GameManager.Table.NumPotChips;
            winner.addWinnings(potAmount);
            app.GameManager.Table.resetPotToZero();

            winnerText = sprintf('%s Wins the pot of %d!\n%s', winner.Name, potAmount, cardsText);
        else
            fprintf('Tie game\n');
            % Split pot logic
            potAmount = app.GameManager.Table.NumPotChips;
            halfPot = floor(potAmount / 2);
            app.GameManager.Player1.addWinnings(halfPot);
            app.GameManager.Player2.addWinnings(potAmount - halfPot);
            app.GameManager.Table.resetPotToZero();

            winnerText = sprintf('Tie Game! Pot split.\n%s', cardsText);
        end

        % Show final result
        app.StatusLabel.Text = winnerText;
        app.updateChipDisplays();

        % Game over
        app.handleGameEnd(winner, 'showdown');
    end
    
    function endGameWithFold(app, winner)
        % This method properly ends the game when someone folds

        % Get the pot amount before settling
        potAmount = app.GameManager.Table.NumPotChips;

        % Settle the pot - winner gets all chips
        app.GameManager.settlePotAfterFold(winner);

        % Update all displays with final state
        app.updateAllDisplaysAfterAction();

        % Show the fold result
        if strcmp(winner.Name, 'Player')
            winnerText = sprintf('You Win! (AI folded) - You gained %d chips', potAmount);
        else
            winnerText = sprintf('AI Wins! (You folded) - You lost %d chips', potAmount);
        end

        app.updateGameStatus(winnerText);

        % Set final game state
        app.GameInProgress = false;
        app.WaitingForPlayerAction = false;

        % DISABLE ALL ACTION BUTTONS PERMANENTLY until new game
        app.disableActionButtons();

        % ONLY enable the new game button
        app.NewGameButton.Enable = 'on';

        % Show final chip counts
        pause(1.5); % Let user see the win message
        finalStatus = sprintf('%s | Final Chips - You: %d | AI: %d', ...
            winnerText, app.GameManager.Player1.NumChips, app.GameManager.Player2.NumChips);
        app.updateGameStatus(finalStatus);

        fprintf('Game ended by fold. Winner: %s, Final chips - Player: %d, AI: %d\n', ...
            winner.Name, app.GameManager.Player1.NumChips, app.GameManager.Player2.NumChips);
    end
    
    
    end

    methods (Access = private)
    
    function handleGameEnd(app, winner, endType)
            % This is for non-fold endings (showdown, etc.)
            if strcmp(endType, 'fold')
                % This should now be handled by endGameWithFold
                app.endGameWithFold(winner);
                return;
            end

            % Check if game over due to chips - if so, let handleGameOver handle it
            if app.GameManager.Player1.NumChips <= 0 || app.GameManager.Player2.NumChips <= 0
                return; % handleGameOver will be called by updateAllDisplaysAfterAction
            end

            % Handle showdown endings
            app.GameInProgress = false;
            app.WaitingForPlayerAction = false;

            % Update final displays
            app.updateAllDisplaysAfterAction();

            % Show winner message
            if isempty(winner)
                winnerText = 'Tie Game - Pot Split!';
            else
                winnerText = sprintf('%s Wins by showdown!', winner.Name);
            end

            app.updateGameStatus(winnerText);

            % Enable new game button, disable action buttons
            app.NewGameButton.Enable = 'on';
            app.disableActionButtons();
        end

    function handleGameError(app, ME)
        app.GameInProgress = false;
        app.WaitingForPlayerAction = false;
        app.enableNewGameButton();
        uialert(app.UIFigure, ['Game error: ' ME.message], 'Error');
    end
    
    end
    
    methods (Access = private)
    
    function startAITraining(app, numGames)
        % Initialize training state
        app.TrainingInProgress = true;
        app.StopTrainingFlag = false;
        app.TrainingStartTime = tic;
        
        % Update UI for training mode
        app.StartTrainingButton.Enable = 'off';
        app.StopTrainingButton.Enable = 'on';
        app.NumGamesField.Editable = 'off';
        app.TrainStatusLabel.Text = 'Initializing training...';
        app.TrainingProgressBar.Value = 0;
        
        % Initialize or setup game manager for training
        if isempty(app.GameManager)
            app.GameManager = RLLeducPokerManager();
        end
        
        % Setup two AI players for training
        app.GameManager.setupPlayers(true, true);
        
        % Set training parameters
        if ~isempty(app.LearningRateField.Value)
            learningRate = str2double(app.LearningRateField.Value);
            if ~isnan(learningRate)
                app.GameManager.Player1.LearningRate = learningRate;
                app.GameManager.Player2.LearningRate = learningRate;
            end
        end
        
        % Start training with progress updates
        app.runTrainingWithProgress(numGames);
    end
    
    function runTrainingWithProgress(app, numGames)
        % Training statistics
        player1Wins = 0;
        player2Wins = 0;
        ties = 0;
        
        % Progress tracking
        progressStep = max(1, floor(numGames/100)); % Update every 1%
            
        app.TrainStatusLabel.Text = sprintf('Training: 0/%d games completed', numGames);
        
        try
            for gameIdx = 1:numGames
                % Check if stop was requested
                if app.StopTrainingFlag
                    app.TrainStatusLabel.Text = 'Training stopped by user';
                    break;
                end
                
                % Play one training game
                [winner, ~] = app.GameManager.playOneGame();
                
                % Update statistics
                if isempty(winner)
                    ties = ties + 1;
                elseif strcmp(winner.Name, app.GameManager.Player1.Name)
                    player1Wins = player1Wins + 1;
                else
                    player2Wins = player2Wins + 1;
                end
                
                % Update UI periodically
                if mod(gameIdx, progressStep) == 0 || gameIdx == numGames
                    app.updateTrainingProgress(gameIdx, numGames, player1Wins, player2Wins, ties);
                    drawnow; % Force UI update
                end
                
                % Reset for next game
                app.GameManager.resetForNewGame();
                
                % Small pause to prevent UI freezing (optional)
                if mod(gameIdx, 100) == 0
                    pause(0.01);
                end
            end
                    
            app.GameManager.TrainingMode = false;

            % Mark that we have trained AI
            app.HasTrainedAI = true;
            app.TrainedGameManager = app.GameManager; % Store reference

            % Training completed
            if ~app.StopTrainingFlag
                app.onTrainingCompleted(numGames, player1Wins, player2Wins, ties);
            end

        catch ME
            app.onTrainingError(ME);
        end
        
        % Reset UI state
        app.resetTrainingUI();
    end
    
    function updateTrainingProgress(app, currentGame, totalGames, p1Wins, p2Wins, ties)
        % Update progress bar
        progress = (currentGame / totalGames) * 100;
        app.TrainingProgressBar.Value = progress;
        
        % Update status
        app.TrainStatusLabel.Text = sprintf('Training: %d/%d games (%.1f%%)', ...
            currentGame, totalGames, progress);
        
        % Update statistics
        if currentGame > 0
            p1WinRate = (p1Wins / currentGame) * 100;
            p2WinRate = (p2Wins / currentGame) * 100;
            tieRate = (ties / currentGame) * 100;
            
            statsText = sprintf('Player 1: %d wins (%.1f%%) | Player 2: %d wins (%.1f%%) | Ties: %d (%.1f%%)', ...
                p1Wins, p1WinRate, p2Wins, p2WinRate, ties, tieRate);
            app.WinsStatsLabel.Text = statsText;
        end
        
        % Update time estimate
        if currentGame > 10 % Only estimate after some games
            elapsed = toc(app.TrainingStartTime);
            estimatedTotal = elapsed * (totalGames / currentGame);
            remaining = estimatedTotal - elapsed;
            
            app.TrainingTimeLabel.Text = sprintf('Elapsed: %.1fs | Est. remaining: %.1fs', ...
                elapsed, remaining);
        end
    end
    
    function onTrainingCompleted(app, totalGames, p1Wins, p2Wins, ties)
        elapsed = toc(app.TrainingStartTime);

        app.TrainStatusLabel.Text = sprintf('Training completed! %d games in %.1f seconds', ...
            totalGames, elapsed);

        % Show final statistics
        p1WinRate = (p1Wins / totalGames) * 100;
        p2WinRate = (p2Wins / totalGames) * 100;
        tieRate = (ties / totalGames) * 100;

        finalStats = sprintf('Final Results:\nPlayer 1: %d wins (%.1f%%)\nPlayer 2: %d wins (%.1f%%)\nTies: %d (%.1f%%)\n\nAI is now trained and ready for gameplay!', ...
            p1Wins, p1WinRate, p2Wins, p2WinRate, ties, tieRate);

        uialert(app.UIFigure, finalStats, 'Training Complete', 'Icon', 'success');

        % The trained models are already in the GameManager's players
        % No need to transfer anything - they're ready to use!
    end
    
    function onTrainingError(app, ME)
        app.TrainStatusLabel.Text = 'Training error occurred';
        uialert(app.UIFigure, ['Training error: ' ME.message], 'Training Error');
    end
    
    function resetTrainingUI(app)
        app.TrainingInProgress = false;
        app.StopTrainingFlag = false;
        
        app.StartTrainingButton.Enable = 'on';
        app.StopTrainingButton.Enable = 'off';
        app.NumGamesField.Editable = 'on';
        app.TrainingProgressBar.Value = 0;
    end
    
    end
    
    methods (Access = private)
    
    function gameOver = checkForGameOver(app)
        gameOver = false;
        
        % Check if either player has run out of chips
        if app.GameManager.Player1.NumChips <= 0 || app.GameManager.Player2.NumChips <= 0
            gameOver = true;
            app.handleGameOver();
        end
    end
    
    function handleGameOver(app)
        % Game over - someone is out of chips
        app.GameInProgress = false;
        app.WaitingForPlayerAction = false;
        
        % Determine who ran out of chips
        if app.GameManager.Player1.NumChips <= 0 && app.GameManager.Player2.NumChips <= 0
            gameOverText = 'Game Over: Both players are out of chips!';
        elseif app.GameManager.Player1.NumChips <= 0
            gameOverText = 'Game Over: You are out of chips! AI wins the session.';
        else
            gameOverText = 'Game Over: AI is out of chips! You win the session.';
        end
        
        app.StatusLabel.Text = gameOverText;
        
        % Disable all game buttons
        app.disableAllGameButtons();
        
        % Show game over dialog
        finalChips = sprintf('Final Chip Counts:\nYou: %d chips\nAI: %d chips', ...
            app.GameManager.Player1.NumChips, app.GameManager.Player2.NumChips);
        
        uialert(app.UIFigure, [gameOverText newline newline finalChips], ...
            'Game Over', 'Icon', 'info');
    end
    
    function disableAllGameButtons(app)
        % Disable all action buttons
        app.CheckCallButton.Enable = 'off';
        app.BetRaiseButton.Enable = 'off';
        app.FoldButton.Enable = 'off';
        app.NewGameButton.Enable = 'off';
        
        % Only keep the back to menu button enabled
        app.BacktoMainMenuButton.Enable = 'on';
    end
    
    function enableGameButtons(app)
        % Re-enable buttons when starting a new session
        app.CheckCallButton.Enable = 'on';
        app.BetRaiseButton.Enable = 'on';
        app.FoldButton.Enable = 'on';
        app.NewGameButton.Enable = 'on';
        app.BacktoMainMenuButton.Enable = 'on';
    end

    function completeGameReset(app)
        % Reset ALL game state flags
        app.GameInProgress = false;
        app.WaitingForPlayerAction = false;
        app.CurrentGameStage = "";
        app.PlayerAction = "";
        app.setupPreFlopBetting();
        % Reset UI state
        app.disableActionButtons();
        app.NewGameButton.Enable = 'off'; % Disable during setup

        % Clear any pending UI updates
        drawnow;

        fprintf('Complete game reset performed\n'); % Debug
    end
    
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            try
                % Use trained GameManager if available, otherwise create new one
                if app.HasTrainedAI && ~isempty(app.TrainedGameManager)
                    app.GameManager = app.TrainedGameManager;
                    fprintf('Using trained AI model\n');
                else
                    if isempty(app.GameManager)
                        app.GameManager = RLLeducPokerManager();
                    end
                    fprintf('Using untrained AI model\n');
                end

                % Setup players for gameplay (not training)
                app.setupPlayersForGame();

                % Switch to play game panel
                app.showPlayGame();

                % Initialize the game interface
                app.initializeGameInterface();

            catch ME
                uialert(app.UIFigure, ME.message, 'Error starting game');
            end
        end

        % Button pushed function: TrainAIButton
        function TrainAIButtonPushed(app, event)
            try
                % Initialize the game manager if not already done
                if isempty(app.GameManager)
                    app.GameManager = RLLeducPokerManager();
                end

                % Switch to training panel
                app.showTrainAI();

                % Initialize training display
                app.initializeTrainingDisplay();

            catch ME
                uialert(app.UIFigure, ME.message, 'Error accessing training');
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Confirm exit
            answer = uiconfirm(app.UIFigure, 'Are you sure you want to exit?', 'Confirm Exit');
            if strcmp(answer, 'OK')
                delete(app);
            end
        end

        % Button pushed function: CheckCallButton
        function CheckCallButtonPushed(app, event)
            fprintf('=== CHECK/CALL BUTTON PRESSED ===\n');

            if ~app.GameInProgress || ~app.WaitingForPlayerAction
                return; % Ignore if not player's turn
            end

            try
                % Process the player's bet/raise action
                app.processPlayerAction('C');

            catch ME
                uialert(app.UIFigure, ['Error processing action: ' ME.message], 'Game Error');
            end
        end

        % Button pushed function: BetRaiseButton
        function BetRaiseButtonPushed(app, event)
            if ~app.GameInProgress || ~app.WaitingForPlayerAction
                return; % Ignore if not player's turn
            end

            try
                % Process the player's bet/raise action
                app.processPlayerAction('B');

            catch ME
                uialert(app.UIFigure, ['Error processing action: ' ME.message], 'Game Error');
            end
        end

        % Button pushed function: FoldButton
        function FoldButtonPushed(app, event)
            if ~app.GameInProgress || ~app.WaitingForPlayerAction
                return; % Ignore if not player's turn
            end

            try
                % Confirm fold action
                answer = uiconfirm(app.UIFigure, 'Are you sure you want to fold?', 'Confirm Fold');
                if strcmp(answer, 'OK')
                    app.processPlayerAction('F');
                end

            catch ME
                uialert(app.UIFigure, ['Error processing action: ' ME.message], 'Game Error');
            end
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)

            fprintf('New Game button pressed!\n'); % Debug output

            try 
                app.completeGameReset();

                if isempty(app.GameManager)
                    app.GameManager = RLLeducPokerManager();
                    app.GameManager.setupPlayers(false, true);
                end

                % Reset for new game
                app.GameManager.resetForNewGame();
                app.clearCardDisplays();
                app.GameManager.dealStartingCards();
                app.GameManager.addBlindBetsToPot();

                % Update displays
                app.PotLabel.Text = sprintf('Pot: %d', app.GameManager.Table.NumPotChips);
                app.PlayerChipsLabel.Text = sprintf('Your Chips: %d', app.GameManager.Player1.NumChips);
                app.AIChipsLabel.Text = sprintf('AI Chips: %d', app.GameManager.Player2.NumChips);
                app.PlayerBetLabel.Text = 'Player bet: ';
                app.AIBetLabel.Text = 'AI bet: ';
               
                % Show player's card
                playerCard = app.GameManager.Player1.Card;
                app.displayPlayerCard();
                cardText = sprintf('Your card: %s of %s', playerCard.Rank, playerCard.Suit);
                app.StatusLabel.Text = ['Pre-Flop: Your turn! ' cardText];
                app.PlayerCardLabel.Text = cardText;
                app.WinnerLabel.Text = '';
                % SET THE CRITICAL FLAGS
                app.GameInProgress = true;
                app.WaitingForPlayerAction = true;
                app.CurrentGameStage = "PreFlop";

                fprintf('Game flags set - GameInProgress: %d, WaitingForPlayerAction: %d\n', ...
                    app.GameInProgress, app.WaitingForPlayerAction);

                % Enable action buttons
                app.enableActionButtons();
                app.NewGameButton.Enable = 'off';

                fprintf('New game started successfully!\n');

            catch ME
                fprintf('Error in NewGameButtonPushed: %s\n', ME.message);
                uialert(app.UIFigure, ['Error starting new game: ' ME.message], 'Game Error');

            end
        end

        % Button pushed function: StartTrainingButton
        function StartTrainingButtonPushed(app, event)
            try
                % Get number of games from input
                numGames = str2double(app.NumGamesField.Value);

                % Validate input
                if isnan(numGames) || numGames <= 0
                    uialert(app.UIFigure, 'Please enter a valid number of training games', 'Invalid Input');
                    return;
                end

                if numGames > 10000
                    answer = uiconfirm(app.UIFigure, ...
                        sprintf('Training %d games may take a while. Continue?', numGames), ...
                        'Confirm Training');
                    if strcmp(answer, 'Cancel')
                        return;
                    end
                end

                % Initialize training
                app.startAITraining(numGames);

            catch ME
                uialert(app.UIFigure, ['Error starting training: ' ME.message], 'Training Error');
            end
        end

        % Button pushed function: StopTrainingButton
        function StopTrainingButtonPushed(app, event)
            % Set flag to stop training
            app.StopTrainingFlag = true;
            app.TrainStatusLabel.Text = 'Stopping training...';
            app.StopTrainingButton.Enable = 'off';
        end

        % Button pushed function: BackToMenuButton
        function BackToMenuButtonPushed(app, event)
            if app.TrainingInProgress
                answer = uiconfirm(app.UIFigure, ...
                    'Training is in progress. Stop training and return to menu?', ...
                    'Confirm');
                if strcmp(answer, 'Cancel')
                    return;
                end
                app.StopTrainingFlag = true;
            end

            app.showMainMenu();
        end

        % Button pushed function: BacktoMainMenuButton
        function BacktoMainMenuButtonPushed(app, event)
            % Check if game is in progress
            if app.GameInProgress
                answer = uiconfirm(app.UIFigure, ...
                    'A game is in progress. Are you sure you want to return to the main menu?', ...
                    'Confirm Return to Menu', ...
                    'Options', {'Yes', 'No'}, ...
                    'DefaultOption', 'No');

                if strcmp(answer, 'No')
                    return;
                end
            end

            % Reset game state
            app.GameInProgress = false;
            app.WaitingForPlayerAction = false;

            % Return to main menu
            app.showMainMenu();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlayGamePanel = uipanel(app.UIFigure);
            app.PlayGamePanel.Title = 'Play Game Panel';
            app.PlayerChipsLabel = uilabel(app.PlayGamePanel);
            app.PlayerChipsLabel.Text = 'Player Chips';
            app.AIChipsLabel = uilabel(app.PlayGamePanel);
            app.AIChipsLabel.Text = 'AI Chips ';
            app.StatusLabel = uilabel(app.PlayGamePanel);
            app.StatusLabel.Text = 'Status';
            app.GameStageLabel = uilabel(app.PlayGamePanel);
            app.GameStageLabel.Text = 'Game Stage';
            app.TurnIndicatorLabel = uilabel(app.PlayGamePanel);
            app.TurnIndicatorLabel.Text = 'Turn Indicator                                                                                                               ';
            app.WinnerLabel = uilabel(app.PlayGamePanel);
            app.WinnerLabel.Text = 'Winner ';
            app.CheckCallButton = uibutton(app.PlayGamePanel, 'push');
            app.CheckCallButton.ButtonPushedFcn = createCallbackFcn(app, @CheckCallButtonPushed, true);
            app.CheckCallButton.Text = 'Check Call';
            app.BetRaiseButton = uibutton(app.PlayGamePanel, 'push');
            app.BetRaiseButton.ButtonPushedFcn = createCallbackFcn(app, @BetRaiseButtonPushed, true);
            app.BetRaiseButton.Text = 'Bet Raise';
            app.FoldButton = uibutton(app.PlayGamePanel, 'push');
            app.FoldButton.ButtonPushedFcn = createCallbackFcn(app, @FoldButtonPushed, true);
            app.FoldButton.Text = 'Fold';
            app.NewGameButton = uibutton(app.PlayGamePanel, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.PlayerCardImage = uiimage(app.PlayGamePanel);
            app.CommunityCardImage = uiimage(app.PlayGamePanel);
            app.AICardImage = uiimage(app.PlayGamePanel);
            app.PlayerCardLabel = uilabel(app.PlayGamePanel);
            app.PlayerCardLabel.Text = 'Player Card';
            app.CommunityCardLabel = uilabel(app.PlayGamePanel);
            app.CommunityCardLabel.Text = 'Community Card';
            app.AICardLabel = uilabel(app.PlayGamePanel);
            app.AICardLabel.Text = 'AI Card';
            app.PlayerBetLabel = uilabel(app.PlayGamePanel);
            app.PlayerBetLabel.Text = 'Player Bet';
            app.AIBetLabel = uilabel(app.PlayGamePanel);
            app.AIBetLabel.Text = 'AI Bet';
            app.PotLabel = uilabel(app.PlayGamePanel);
            app.PotLabel.Text = 'Pot ';
            app.PlaytheGameLabel = uilabel(app.PlayGamePanel);
            app.PlaytheGameLabel.Text = 'Play the Game';
            app.BacktoMainMenuButton = uibutton(app.PlayGamePanel, 'push');
            app.BacktoMainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoMainMenuButtonPushed, true);
            app.BacktoMainMenuButton.Text = 'Back to Main Menu';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings Panel';
            app.TrainAIPanel = uipanel(app.UIFigure);
            app.TrainAIPanel.Title = 'Train AI Panel';
            app.StartTrainingButton = uibutton(app.TrainAIPanel, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingButtonPushed, true);
            app.StartTrainingButton.Text = 'Start Training';
            app.StopTrainingButton = uibutton(app.TrainAIPanel, 'push');
            app.StopTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StopTrainingButtonPushed, true);
            app.StopTrainingButton.Text = 'Stop Training';
            app.BackToMenuButton = uibutton(app.TrainAIPanel, 'push');
            app.BackToMenuButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMenuButtonPushed, true);
            app.BackToMenuButton.Text = 'Back To Menu';
            app.TrainTitleLabel = uilabel(app.TrainAIPanel);
            app.TrainTitleLabel.Text = 'AI Training';
            app.TrainStatusLabel = uilabel(app.TrainAIPanel);
            app.TrainStatusLabel.Text = 'Training Status';
            app.Label = uilabel(app.TrainAIPanel);
            app.Label.Text = '';
            app.WinsStatsLabel = uilabel(app.TrainAIPanel);
            app.WinsStatsLabel.Text = 'Wins Stats';
            app.TrainingTimeLabel = uilabel(app.TrainAIPanel);
            app.TrainingTimeLabel.Text = 'Training Time';
            app.ProgressLabel = uilabel(app.TrainAIPanel);
            app.ProgressLabel.Text = 'Progress';
            app.TrainingProgressBar = uigauge(app.TrainAIPanel, 'circular');
            app.TextAreaLabel = uilabel(app.TrainAIPanel);
            app.TextAreaLabel.Visible = 'off';
            app.TextAreaLabel.Text = 'Text Area';
            app.TrainingLogTextArea = uitextarea(app.TrainAIPanel);
            app.TrainingLogTextArea.Visible = 'off';
            app.TrainingLogTextArea.Value = {'Training Log'};
            app.NumberofGamesLabel = uilabel(app.TrainAIPanel);
            app.NumberofGamesLabel.Text = 'Number of Games';
            app.NumGamesField = uieditfield(app.TrainAIPanel, 'text');
            app.LearningRateLabel = uilabel(app.TrainAIPanel);
            app.LearningRateLabel.Text = 'Learning Rate';
            app.LearningRateField = uieditfield(app.TrainAIPanel, 'text');
            app.ExplorationRateLabel = uilabel(app.TrainAIPanel);
            app.ExplorationRateLabel.Text = 'Exploration Rate';
            app.ExplorationRateField = uieditfield(app.TrainAIPanel, 'text');
            app.MainMenuPanel = uipanel(app.UIFigure);
            app.MainMenuPanel.Title = 'Main Menu Panel';
            app.PlayButton = uibutton(app.MainMenuPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.TrainAIButton = uibutton(app.MainMenuPanel, 'push');
            app.TrainAIButton.ButtonPushedFcn = createCallbackFcn(app, @TrainAIButtonPushed, true);
            app.TrainAIButton.Text = 'Train AI';
            app.ExitButton = uibutton(app.MainMenuPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.WelcometoLeDucPokerPlayerLabel = uilabel(app.MainMenuPanel);
            app.WelcometoLeDucPokerPlayerLabel.Text = 'Welcome to LeDuc Poker Player';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end